classdef KukaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CameraButton           matlab.ui.control.StateButton
        StopButton             matlab.ui.control.StateButton
        Joint0Slider           matlab.ui.control.Slider
        Joint0SliderLabel      matlab.ui.control.Label
        LeaveButton            matlab.ui.control.Button
        GripButton             matlab.ui.control.Button
        ScreenshotButton       matlab.ui.control.Button
        Joint4Slider           matlab.ui.control.Slider
        Joint4SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        Back_Left              matlab.ui.control.Button
        Back_Right             matlab.ui.control.Button
        Up_Left                matlab.ui.control.Button
        SideMotionSlider       matlab.ui.control.Slider
        SideMotionSliderLabel  matlab.ui.control.Label
        Up_Right               matlab.ui.control.Button
        FrowardBackwordSlider  matlab.ui.control.Slider
        FrowardBackwordLabel   matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: FrowardBackwordSlider
        function FrowardBackwordSliderValueChanging(app, event)
            
        
             changingValue = event.Value;
                       
  
sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl,changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr,changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,changingValue, sim.simx_opmode_streaming);
   
[errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
else
    disp('Failed Connecting to Remote API');
end

        end

        % Value changing function: SideMotionSlider
        function SideMotionSliderValueChanging2(app, event)
            changingValue = event.Value;

sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,  changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl, -changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr, -changingValue, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,changingValue, sim.simx_opmode_streaming);
[errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
 
else
    disp('Failed Connecting to Remote API');
end
        end

        % Button pushed function: Back_Right
        function Back_RightButtonPushed(app, event)

  
sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,-3, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,-3, sim.simx_opmode_streaming);


else
    disp('Failed Connecting to Remote API');
end
        end

        % Button pushed function: Back_Left
        function Back_LeftButtonPushed(app, event)
             
  
sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl,-3, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr,-3, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,0, sim.simx_opmode_streaming);


else
    disp('Failed Connecting to Remote API');
end
        end

        % Button pushed function: Up_Left
        function Up_LeftButtonPushed(app, event)
             
  
sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,  3, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,  3, sim.simx_opmode_streaming);


else
    disp('Failed Connecting to Remote API');
end
        end

        % Button pushed function: Up_Right
        function Up_RightButtonPushed(app, event)
             
  
sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr,  3, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr,0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,  3, sim.simx_opmode_streaming);
 
else
    disp('Failed Connecting to Remote API');
end
        end

        % Value changing function: Joint0Slider
        function Joint0SliderValueChanging(app, event)
            changingValue = event.Value;
             sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');

     
[errorCode, joint0]=sim.simxGetObjectHandle(clientID,'youBotArmJoint0', sim.simx_opmode_blocking);

[errorCode]=sim.simxSetJointTargetPosition(clientID, joint0, (changingValue/100)*pi, sim.simx_opmode_streaming);
 [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
  else
    disp('Failed Connecting to Remote API');
end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');

     
[errorCode, joint1]=sim.simxGetObjectHandle(clientID,'youBotArmJoint1', sim.simx_opmode_blocking);

[errorCode]=sim.simxSetJointTargetPosition(clientID, joint1, (changingValue/100)*pi, sim.simx_opmode_streaming);
[errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
 
 else
    disp('Failed Connecting to Remote API');
end

        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');

     
[errorCode, joint2]=sim.simxGetObjectHandle(clientID,'youBotArmJoint2', sim.simx_opmode_blocking);

[errorCode]=sim.simxSetJointTargetPosition(clientID, joint2, (changingValue/100)*pi, sim.simx_opmode_streaming);

 [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
  else
    disp('Failed Connecting to Remote API');
end

        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');

     
[errorCode, joint3]=sim.simxGetObjectHandle(clientID,'youBotArmJoint3', sim.simx_opmode_blocking);

[errorCode]=sim.simxSetJointTargetPosition(clientID, joint3, (changingValue/100)*pi, sim.simx_opmode_streaming);
 
  [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
   
else
    disp('Failed Connecting to Remote API');
end

        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');

     
[errorCode, joint4]=sim.simxGetObjectHandle(clientID,'youBotArmJoint4', sim.simx_opmode_blocking);

[errorCode]=sim.simxSetJointTargetPosition(clientID, joint4,(changingValue/100)*pi, sim.simx_opmode_streaming);

 [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
  
else
    disp('Failed Connecting to Remote API');
end

        end

        % Button pushed function: LeaveButton
        function LeaveButtonPushed(app, event)
                      sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
[errorCode, grib1]=sim.simxGetObjectHandle(clientID,'G1', sim.simx_opmode_blocking);
[errorCode, grib2]=sim.simxGetObjectHandle(clientID,'G2', sim.simx_opmode_blocking);
[errorCode]= sim.simxSetJointTargetVelocity(clientID,grib2,-0.04, sim.simx_opmode_oneshot_wait);
[errorCode]= sim.simxSetJointTargetPosition(clientID,grib1,0.02,sim.simx_opmode_oneshot_wait);

[errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
else
    disp('Failed Connecting to Remote API');
end


        end

        % Button pushed function: GripButton
        function GripButtonPushed(app, event)
                      sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
[errorCode, grib1]=sim.simxGetObjectHandle(clientID,'G1', sim.simx_opmode_blocking);
[errorCode, grib2]=sim.simxGetObjectHandle(clientID,'G2', sim.simx_opmode_blocking);
[errorCode]= sim.simxSetJointTargetVelocity(clientID,grib2,0.03, sim.simx_opmode_oneshot_wait);
[errorCode]= sim.simxSetJointTargetPosition(clientID,grib1,0.02,sim.simx_opmode_oneshot_wait);
 
 [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)
    pause(0.1);
end
else
    disp('Failed Connecting to Remote API');
end


        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
    
[errorCode, wheel_rr]=sim.simxGetObjectHandle(clientID,'rollingJoint_rr', sim.simx_opmode_blocking);
[errorCode, wheel_rl]=sim.simxGetObjectHandle(clientID,'rollingJoint_rl', sim.simx_opmode_blocking);
[errorCode, wheel_fr]=sim.simxGetObjectHandle(clientID,'rollingJoint_fr', sim.simx_opmode_blocking);
[errorCode, wheel_fl]=sim.simxGetObjectHandle(clientID,'rollingJoint_fl', sim.simx_opmode_blocking);


[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rr, 0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_rl, 0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fr, 0, sim.simx_opmode_streaming);
[errorCode]=sim.simxSetJointTargetVelocity(clientID,wheel_fl,0, sim.simx_opmode_streaming);

else
    disp('Failed Connecting to Remote API');
end
        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
          
            sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
            x=0;
             [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
  
pause(0.1)
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
newimg=["copp"+x+".jpg"];
x=x+1;
imwrite(image,newimg)
else
    disp('Failed Connecting to Remote API');
end


        end

        % Value changed function: CameraButton
        function CameraButtonValueChanged(app, event)
            value = app.CameraButton.Value;
              sim=remApi('remoteApi'); 
sim.simxFinish(-1); 
clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
if (clientID>-1)
    disp('Connected to remote API server');
            [errorCode, cam]=sim.simxGetObjectHandle(clientID,'Vision_sensor', sim.simx_opmode_blocking);
[errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_streaming);
for ii=1:50

  [errorCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,cam,0,sim.simx_opmode_buffer);
    imshow(image, 'Parent',app.UIAxes)

pause(0.1);
end
else
    disp('Failed Connecting to Remote API');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live Feed')
            app.FrowardBackwordLabel = uilabel(app.UIFigure);
            app.FrowardBackwordLabel.Text = {'Froward'; '/Backword'};
            app.FrowardBackwordSlider = uislider(app.UIFigure);
            app.FrowardBackwordSlider.Limits = [-5 5];
            app.FrowardBackwordSlider.ValueChangingFcn = createCallbackFcn(app, @FrowardBackwordSliderValueChanging, true);
            app.Up_Right = uibutton(app.UIFigure, 'push');
            app.Up_Right.ButtonPushedFcn = createCallbackFcn(app, @Up_RightButtonPushed, true);
            app.Up_Right.Text = {'Up_Right'; ''};
            app.SideMotionSliderLabel = uilabel(app.UIFigure);
            app.SideMotionSliderLabel.Text = 'Side Motion';
            app.SideMotionSlider = uislider(app.UIFigure);
            app.SideMotionSlider.Limits = [-5 5];
            app.SideMotionSlider.ValueChangingFcn = createCallbackFcn(app, @SideMotionSliderValueChanging2, true);
            app.Up_Left = uibutton(app.UIFigure, 'push');
            app.Up_Left.ButtonPushedFcn = createCallbackFcn(app, @Up_LeftButtonPushed, true);
            app.Up_Left.Text = 'Up_Left';
            app.Back_Right = uibutton(app.UIFigure, 'push');
            app.Back_Right.ButtonPushedFcn = createCallbackFcn(app, @Back_RightButtonPushed, true);
            app.Back_Right.Text = 'Back_Right';
            app.Back_Left = uibutton(app.UIFigure, 'push');
            app.Back_Left.ButtonPushedFcn = createCallbackFcn(app, @Back_LeftButtonPushed, true);
            app.Back_Left.Text = 'Back_Left';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-100 100];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-100 100];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-100 100];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-100 100];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.ScreenshotButton = uibutton(app.UIFigure, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = {'Screenshot'; ''};
            app.GripButton = uibutton(app.UIFigure, 'push');
            app.GripButton.ButtonPushedFcn = createCallbackFcn(app, @GripButtonPushed, true);
            app.GripButton.Text = 'Grip';
            app.LeaveButton = uibutton(app.UIFigure, 'push');
            app.LeaveButton.ButtonPushedFcn = createCallbackFcn(app, @LeaveButtonPushed, true);
            app.LeaveButton.Text = {'Leave'; ''};
            app.Joint0SliderLabel = uilabel(app.UIFigure);
            app.Joint0SliderLabel.Text = 'Joint0';
            app.Joint0Slider = uislider(app.UIFigure);
            app.Joint0Slider.Limits = [-100 100];
            app.Joint0Slider.ValueChangingFcn = createCallbackFcn(app, @Joint0SliderValueChanging, true);
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.CameraButton = uibutton(app.UIFigure, 'state');
            app.CameraButton.ValueChangedFcn = createCallbackFcn(app, @CameraButtonValueChanged, true);
            app.CameraButton.Text = 'Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KukaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end